classdef EqualizerApp_RyabchikovaM2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure      matlab.ui.Figure
        gain1         matlab.ui.control.Label
        gane10        matlab.ui.control.Slider
        Label_10      matlab.ui.control.Label
        gane9         matlab.ui.control.Slider
        Label_9       matlab.ui.control.Label
        gane8         matlab.ui.control.Slider
        Label_8       matlab.ui.control.Label
        gane7         matlab.ui.control.Slider
        Label_7       matlab.ui.control.Label
        gane6         matlab.ui.control.Slider
        Label_6       matlab.ui.control.Label
        gane5         matlab.ui.control.Slider
        Label_5       matlab.ui.control.Label
        gane4         matlab.ui.control.Slider
        Label_4       matlab.ui.control.Label
        gane3         matlab.ui.control.Slider
        Label_3       matlab.ui.control.Label
        gane2         matlab.ui.control.Slider
        Label_2       matlab.ui.control.Label
        gane1         matlab.ui.control.Slider
        Label         matlab.ui.control.Label
        Load          matlab.ui.control.Button
        Play          matlab.ui.control.Button
        Pause         matlab.ui.control.Button
        Stop          matlab.ui.control.Button
        Spectrum      matlab.ui.control.UIAxes
        FreqResponce  matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        equalizer = Equalizer(64, 44100);
        deviceWriter = audioDeviceWriter('SampleRate', 44100)  ;
        fileReader = dsp.AudioFileReader();
    end

    properties (Access = protected)
        filename;
        stopFlag = false;
    end

methods
    function PlotFreqResponce(app)
        [Hdb, w] = GetFreqResponce(app.equalizer);
        plot(app.FreqResponce , w, Hdb)
        drawnow
    end  % Description
end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.PlotFreqResponce();
            drawnow
        end

        % Value changed function: gane1
        function gane1ValueChanged(app, event)
            app.equalizer.gane(1) = app.gane1.Value;
            app.PlotFreqResponce();
            drawnow 
            
        end

        % Value changed function: gane2
        function gane2ValueChanged(app, event)
            app.equalizer.gane(2) = app.gane2.Value;
            app.PlotFreqResponce();
            drawnow
        end

        % Value changed function: gane3
        function gane3ValueChanged(app, event)
            app.equalizer.gane(3) = app.gane3.Value;
            app.PlotFreqResponce();
            drawnow
            
        end

        % Value changed function: gane4
        function gane4ValueChanged(app, event)
            app.equalizer.gane(4) = app.gane4.Value;
            app.PlotFreqResponce();
            drawnow
            
        end

        % Value changed function: gane5
        function gane5ValueChanged(app, event)
            app.equalizer.gane(5) = app.gane5.Value;
            app.PlotFreqResponce();
            drawnow
            
        end

        % Value changed function: gane6
        function gane6ValueChanged(app, event)
            app.equalizer.gane(6) = app.gane6.Value;
            app.PlotFreqResponce();
            drawnow
            
        end

        % Value changed function: gane7
        function gane7ValueChanged(app, event)
            app.equalizer.gane(7) = app.gane7.Value;
            app.PlotFreqResponce();
            drawnow
            
        end

        % Value changed function: gane8
        function gane8ValueChanged(app, event)
            app.equalizer.gane(8) = app.gane8.Value;
            app.PlotFreqResponce();
            drawnow
            
        end

        % Value changed function: gane9
        function gane9ValueChanged(app, event)
            app.equalizer.gane(9) = app.gane9.Value;
            app.PlotFreqResponce();
            drawnow
        end

        % Value changed function: gane10
        function gane10ValueChanged(app, event)
            app.equalizer.gane(10) = app.gane10.Value;
            app.PlotFreqResponce();
            drawnow
            
        end

        % Button pushed function: Load
        function LoadButtonPushed(app, event)
            app.filename = uigetfile('*.mp3');
            app.Label.Text = app.filename;
            app.fileReader = dsp.AudioFileReader(app.filename, 'PlayCount', inf, 'SamplesPerFrame', 4096);
        end

        % Button pushed function: Play
        function PlayButtonPushed(app, event)
           app.stopFlag = false;
            while ~isDone(app.fileReader) && ~ app.stopFlag  
                audioData = app.fileReader();
                aoudioDataF = app.equalizer.Filtering(audioData)
                app.deviceWriter(aoudioDataF);
                drawnow
            end
        end

        % Button pushed function: Stop
        function StopButtonPushed(app, event)
            app.stopFlag = false;
            reset(app.deviceWriter)
            reset(app.fileReader)
            
          
        end

        % Button pushed function: Pause
        function PauseButtonPushed(app, event)
              
            app.stopFlag = true;
            drawnow
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.FreqResponce = uiaxes(app.UIFigure);
            title(app.FreqResponce, 'FreqResponce')
            xlabel(app.FreqResponce, 'X')
            ylabel(app.FreqResponce, 'Y')
            zlabel(app.FreqResponce, 'Z')
            app.Spectrum = uiaxes(app.UIFigure);
            title(app.Spectrum, 'Spectrum')
            xlabel(app.Spectrum, 'X')
            ylabel(app.Spectrum, 'Y')
            zlabel(app.Spectrum, 'Z')
            app.Stop = uibutton(app.UIFigure, 'push');
            app.Stop.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.Stop.Text = 'Stop';
            app.Pause = uibutton(app.UIFigure, 'push');
            app.Pause.ButtonPushedFcn = createCallbackFcn(app, @PauseButtonPushed, true);
            app.Pause.Text = 'Pause';
            app.Play = uibutton(app.UIFigure, 'push');
            app.Play.ButtonPushedFcn = createCallbackFcn(app, @PlayButtonPushed, true);
            app.Play.Text = 'Play';
            app.Load = uibutton(app.UIFigure, 'push');
            app.Load.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.Load.Text = 'Load';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '31';
            app.gane1 = uislider(app.UIFigure);
            app.gane1.Limits = [-10 10];
            app.gane1.ValueChangedFcn = createCallbackFcn(app, @gane1ValueChanged, true);
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '62';
            app.gane2 = uislider(app.UIFigure);
            app.gane2.Limits = [-10 10];
            app.gane2.ValueChangedFcn = createCallbackFcn(app, @gane2ValueChanged, true);
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '125';
            app.gane3 = uislider(app.UIFigure);
            app.gane3.Limits = [-10 10];
            app.gane3.ValueChangedFcn = createCallbackFcn(app, @gane3ValueChanged, true);
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = '250';
            app.gane4 = uislider(app.UIFigure);
            app.gane4.Limits = [-10 10];
            app.gane4.ValueChangedFcn = createCallbackFcn(app, @gane4ValueChanged, true);
            app.Label_5 = uilabel(app.UIFigure);
            app.Label_5.Text = '500';
            app.gane5 = uislider(app.UIFigure);
            app.gane5.Limits = [-10 10];
            app.gane5.ValueChangedFcn = createCallbackFcn(app, @gane5ValueChanged, true);
            app.Label_6 = uilabel(app.UIFigure);
            app.Label_6.Text = '1000';
            app.gane6 = uislider(app.UIFigure);
            app.gane6.Limits = [-10 10];
            app.gane6.ValueChangedFcn = createCallbackFcn(app, @gane6ValueChanged, true);
            app.Label_7 = uilabel(app.UIFigure);
            app.Label_7.Text = '2000';
            app.gane7 = uislider(app.UIFigure);
            app.gane7.Limits = [-10 10];
            app.gane7.ValueChangedFcn = createCallbackFcn(app, @gane7ValueChanged, true);
            app.Label_8 = uilabel(app.UIFigure);
            app.Label_8.Text = '4000';
            app.gane8 = uislider(app.UIFigure);
            app.gane8.Limits = [-10 10];
            app.gane8.ValueChangedFcn = createCallbackFcn(app, @gane8ValueChanged, true);
            app.Label_9 = uilabel(app.UIFigure);
            app.Label_9.Text = '8000';
            app.gane9 = uislider(app.UIFigure);
            app.gane9.Limits = [-10 10];
            app.gane9.ValueChangedFcn = createCallbackFcn(app, @gane9ValueChanged, true);
            app.Label_10 = uilabel(app.UIFigure);
            app.Label_10.Text = '1600';
            app.gane10 = uislider(app.UIFigure);
            app.gane10.Limits = [-10 10];
            app.gane10.ValueChangedFcn = createCallbackFcn(app, @gane10ValueChanged, true);
            app.gain1 = uilabel(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = EqualizerApp_RyabchikovaM2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end